classdef dfta5sp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        TestsignalspecificationPanel  matlab.ui.container.Panel
        byLabel                       matlab.ui.control.Label
        SGmodeDropDown                matlab.ui.control.DropDown
        SGmodeDropDownLabel           matlab.ui.control.Label
        CountDropDown                 matlab.ui.control.DropDown
        CountDropDownLabel            matlab.ui.control.Label
        LoopDropDown                  matlab.ui.control.DropDown
        LoopDropDownLabel             matlab.ui.control.Label
        TestmodeDropDown              matlab.ui.control.DropDown
        TestmodeDropDownLabel         matlab.ui.control.Label
        CreditLabel                   matlab.ui.control.Label
        TypeLabel                     matlab.ui.control.Label
        LoadtestsignalsettingButton   matlab.ui.control.Button
        InspectpreviouslyrecordedfilesButton  matlab.ui.control.Button
        QUITButton                    matlab.ui.control.Button
        AnalysisandreportpanelPanel   matlab.ui.container.Panel
        REPORTButton                  matlab.ui.control.Button
        ANALYSYSButton                matlab.ui.control.Button
        TestPanel                     matlab.ui.container.Panel
        SavemeasureddataButton        matlab.ui.control.Button
        STEREORButton                 matlab.ui.control.Button
        STEREOLButton                 matlab.ui.control.Button
        STEREOLRButton                matlab.ui.control.Button
        MONOLRButton                  matlab.ui.control.Button
        MONORButton                   matlab.ui.control.Button
        MONOLButton                   matlab.ui.control.Button
        CalibrationPanel              matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        CalibrationStartButton        matlab.ui.control.Button
        AweightSPLdBDropDown          matlab.ui.control.DropDown
        AweightSPLdBDropDownLabel     matlab.ui.control.Label
        CalibratedLamp                matlab.ui.control.Lamp
        CalibratedLampLabel           matlab.ui.control.Label
        Memo5sButton                  matlab.ui.control.Button
        DFTA5SPLabel                  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        CrDistAxis                    matlab.ui.control.UIAxes
        ImpulseAxis                   matlab.ui.control.UIAxes
        ReverbAxis                    matlab.ui.control.UIAxes
        ResponseAxis                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CommonData % Shared common data
    end
    
    methods (Access = private)
        
        function app = getLoopId(app)
            s = app.LoopDropDown.Items;
            app.CommonData.loopId = 1;
            target = app.LoopDropDown.Value;
            for ii = 1:length(s)
                if contains(s{ii}, target)
                    app.CommonData.loopId = ii;
                end
            end
        end
        
        function app = settingFileRead(app)
            fileId = fopen(app.CommonData.settingFileName);
            if fileId == -1
                %close(app.CommonData.fig);
                close(app.UIFigure);
            else
                fclose(fileId);
                s = readlines(app.CommonData.settingFileName);
                if contains(s(1), "Type ")
                    app.CommonData.Type = erase(s(1), "Type ");
                    app.TypeLabel.Text = char(erase(s(1), "Type "));
                else
                    close(app.CommonData.fig);close(app.UIFigure);
                end
                if contains(s(2), "Credit ")
                    app.CommonData.Credit = erase(s(2), "Credit ");
                    app.CreditLabel.Text = char(erase(s(2), "Credit "));
                else
                    close(app.CommonData.fig);close(app.UIFigure);
                end
                if contains(s(3), "Files ")
                    app.CommonData.loopTypeCount = str2double(erase(s(3), "Files "));
                    app.CommonData.loopFiles = s(4:4-1+app.CommonData.loopTypeCount);
                    disp(app.CommonData.loopFiles)
                else
                    close(app.CommonData.fig);close(app.UIFigure);
                end
            end
        end
        
        function xFix = makeSafeguardLoop(app)
            xst = app.CommonData.loop(app.CommonData.loopId).x;
            fs = app.CommonData.samplingFrequency;
            switch app.SGmodeDropDown.Value
                case {'av-10dB', 'av-0dB'}
                    app.CommonData.LoopName = app.CommonData.loopFiles(app.CommonData.loopId);
                    if size(xst, 2) > 1
                        x = (xst(:,1) + xst(:,2))/2;
                    else
                        x = xst;
                    end
                    xf = fft(x);
                    xfFix = xf;
                    app.CommonData.SGmethod = app.SGmodeDropDown.Value;
                    switch app.SGmodeDropDown.Value
                        case 'av-10dB'
                            thresDb = -10;
                        case 'av-0dB'
                            thresDb = 0;
                    end
                    thLevel = mean(abs(xf)) * 10^(thresDb/20);
                    xfFix(abs(xf)<thLevel) = xfFix(abs(xf)<thLevel) ./ abs(xfFix(abs(xf)<thLevel)) * thLevel;
                    xFix = real(ifft(xfFix));
                case 'sDep-10dB'
                    sgOut = makeFrequencyShapedSGsignal(xst, fs, -10, 3);
                    app.CommonData.LoopName = app.CommonData.loopFiles(app.CommonData.loopId);
                    app.CommonData.SGmethod = app.SGmodeDropDown.Value;
                    xFix = sgOut.xSG;
            end
            xFix = 0.8*xFix/max(abs(xFix));
        end

        function results = generateCalibrationTone(app)
            app.UIFigure.Pointer = 'watch';
            drawnow;
            fs = app.CommonData.samplingFrequency;
            baseLength = fs*20;
            fc = round(1000/sqrt(2)):round(1000*sqrt(2));
            testCalTone = zeros(baseLength, 1);
            tt = (1:baseLength)'/fs;
            for ii = 1:length(fc)
                testCalTone = testCalTone + cos(2*pi*tt*fc(ii) + rand(1,1)*2*pi);
            end
            testCalTone = testCalTone/max(abs(testCalTone))*0.8;
            app.CommonData.testCalTone = testCalTone;
            app.CommonData.calLevel = 0;
            app.Label.Visible = 'off';
            app.CalibratedLamp.Visible = 'off';
            app.AweightSPLdBDropDown.Enable = 'off';
            app.UIFigure.Pointer = 'arrow';
            drawnow;
            app.CalibrationPanel.Enable = 'on';
            results = 1;
        end
        
        function results = statusCheck(app)
            disableAllInteractions(app);
            app.CommonData.logFileFid = -1;
            app.RightPanel.Visible = 'off';
            info = audiodevinfo;
            nInput = length(info.input);
            nOutput = length(info.output);
            inputDevices = cell(size(info.input));
            outputDevices = cell(size(info.output));
            for ii = 1:nInput
                inputDevices{ii} = info.input(ii).Name;
            end
            for ii = 1:nOutput
                outputDevices{ii} = info.output(ii).Name;
            end
            [inputDeviceID,tf] = listdlg('PromptString', ...
                {'Select the input device'}, 'ListString',inputDevices, ...'
                'SelectionMode','single','ListSize', [280, 180]);
            if tf
                app.CommonData.inputDeviceID = info.input(inputDeviceID).ID;
                app.CommonData.inputDeviceName = inputDevices{inputDeviceID};
            else
                QUITButtonPushed(app);
            end
            [outputDeviceID,tf] = listdlg('PromptString', ...
                {'Select the output device'}, 'ListString',outputDevices, ...
                'SelectionMode','single', 'ListSize', [280, 180]);
            if tf
                app.CommonData.outputDeviceID = info.output(outputDeviceID).ID;
                app.CommonData.outputDeviceName = outputDevices{outputDeviceID};
            else
                QUITButtonPushed(app);
            end
            enableAllInteractions(app);
            results = 1;
        end
        
        function results = disableAllInteractions(app)
            app.LeftPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';
            results = 1;
        end
        
        function results = enableAllInteractions(app)
            app.LeftPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';
            results = 1;
        end
                
        function y = makeSafeguardSong(app)
            switch app.TestmodeDropDown.Value
                case 'Basic'
                    getLoopId(app);
                    x = makeSafeguardLoop(app);
                    y = zeros(app.CommonData.loopLength * app.CommonData.loopCount, 1);
                    baseIdx = 1:app.CommonData.loopLength;
                    for ii = 1:app.CommonData.loopCount
                        y((ii-1)*app.CommonData.loopLength + baseIdx) = x;
                    end
                case 'Detail'
                    y = zeros(app.CommonData.loopLength ...
                        * app.CommonData.loopCount*app.CommonData.loopTypeCount, 1);
                    for jj = 1:app.CommonData.loopTypeCount
                        app.CommonData.loopId = jj;
                        x = makeSafeguardLoop(app);
                        baseIdx = 1:app.CommonData.loopLength;
                        headL = (jj-1)*app.CommonData.loopLength*app.CommonData.loopCount;
                        for ii = 1:app.CommonData.loopCount
                            y(headL+(ii-1)*app.CommonData.loopLength + baseIdx) = x;
                        end
                    end
            end
        end
        
        function results = disableAllControlPanesl(app)
            app.AnalysisandreportpanelPanel.Enable = 'off';
            app.TestsignalspecificationPanel.Enable = 'off';
            app.TestPanel.Enable = 'off';
            app.CalibrationPanel.Enable = 'off';
            results = 1;
        end
        
        function results = makeStimulusAndPresent(app)
            app.CommonData.logStging = {"test signal generation:" + datestr(now), ...
                "test mode:" + app.TestmodeDropDown.Value, ...
                "SG mode:" + app.SGmodeDropDown.Value, ...
                "contents:" + app.LoopDropDown.Value, ...
                "loop count:" + app.CountDropDown.Value};
            writeLog(app);
            disableAllInteractions(app);
            fs = app.CommonData.samplingFrequency;
            app.Memo5sButton.Enable = 'off';
            y = makeSafeguardSong(app);
            switch app.TestmodeDropDown.Value
                case 'Basic'
                    typeCount = 1;
                case 'Detail'
                    typeCount = app.CommonData.loopTypeCount;
            end
            switch app.CommonData.pushedButton
                case 'MONOL'
                    y = [y y*0];
                case 'MONOR'
                    y = [y*0 y];
                case 'MONOLR'
                    y = [y y];
            end
            app.CommonData.signalSpecStr = "DFTA5SP " + num2str(app.CommonData.loopLength) + ...
                " " + num2str(app.CommonData.loopCount) + " " + num2str(typeCount) ...
                + " " + app.CommonData.SGmethod;
            app.CommonData.testSignal = y;
            app.CommonData.audioRecorder = audiorecorder(fs,24,1,app.CommonData.inputDeviceID);
            record(app.CommonData.audioRecorder);
            pause(1);
            app.CommonData.audioPlayer = audioplayer(y,fs,24,app.CommonData.outputDeviceID);
            playblocking(app.CommonData.audioPlayer);
            pause(1);
            app.CommonData.mesData = getaudiodata(app.CommonData.audioRecorder);
            stop(app.CommonData.audioRecorder);
            app.CommonData.mesCalStr = "Calibration " + num2str(app.CommonData.calLevel);
            app.SavemeasureddataButton.Enable = 'on';
            app.Memo5sButton.Enable = 'on';
            enableAllInteractions(app);
            results = 1;
        end
        
        function results = writeLog(app)
            nLines = length(app.CommonData.logStging);
            for ii = 1:nLines
                fprintf(app.CommonData.logFileFid,'%s\n', ...
                    app.CommonData.logStging{ii});
            end
            results = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            statusCheck(app);
            disableAllControlPanesl(app);
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.CommonData.logStging = {"measurement completed:" + datestr(now)};
            writeLog(app);
            fclose(app.CommonData.logFileFid);
            close(app.UIFigure)
        end

        % Button pushed function: MONOLButton
        function MONOLButtonPushed(app, event)
            app.CommonData.logStging = {"MONOL pushed:" + datestr(now)};
            writeLog(app);
            app.CommonData.pushedButton = 'MONOL';
            makeStimulusAndPresent(app);
        end

        % Button pushed function: SavemeasureddataButton
        function SavemeasureddataButtonPushed(app, event)
            app.CommonData.logStging = {"SAVE pushed:" + datestr(now)};
            writeLog(app);
            fs = app.CommonData.samplingFrequency;
            [~,fn,fe]=fileparts(app.CommonData.settingFileName);
            dateData = string(datestr(now,30));
            testName = app.CommonData.workDirectory + "testSig" + dateData + ".wav";
            mesName = app.CommonData.workDirectory + "mesSig" + dateData + ".wav";
            audiowrite(testName, app.CommonData.testSignal, fs, "BitsPerSample", 24, ...
                "Title", app.CommonData.LoopName,"Comment", app.CommonData.signalSpecStr, ...
                "Artist", fn + fe + " " + app.SGmodeDropDown.Value ...
                + " " + app.TestmodeDropDown.Value);
            audiowrite(mesName, app.CommonData.mesData, fs, "BitsPerSample", 24, ...
                "Comment",app.CommonData.mesCalStr);
            app.CommonData.testName = testName;
            app.CommonData.mesName = mesName;
            app.ANALYSYSButton.Enable = 'on';
            app.RightPanel.Enable = 'off';
            app.SavemeasureddataButton.Enable = 'off';
        end

        % Value changed function: CountDropDown
        function CountDropDownValueChanged(app, event)
            value = app.CountDropDown.Value;
            %disp(str2double(value))
            app.CommonData.loopCount = str2double(value);
        end

        % Button pushed function: MONORButton
        function MONORButtonPushed(app, event)
            app.CommonData.logStging = {"MONOR pushed:" + datestr(now)};
            writeLog(app);
            app.CommonData.pushedButton = 'MONOR';
            makeStimulusAndPresent(app);
        end

        % Button pushed function: MONOLRButton
        function MONOLRButtonPushed(app, event)
            app.CommonData.logStging = {"MONOLR pushed:" + datestr(now)};
            writeLog(app);
            app.CommonData.pushedButton = 'MONOLR';
            makeStimulusAndPresent(app);
        end

        % Button pushed function: ANALYSYSButton
        function ANALYSYSButtonPushed(app, event)
            app.CommonData.logStging = {"ANALYSIS pushed:" + datestr(now)};
            writeLog(app);
            app.UIFigure.Pointer='watch';
            drawnow;
            output = analyzeSafeguardedMeasurement(app.CommonData.testName,...
                app.CommonData.mesName);
            app.CommonData.analysisResult = output;
            app.CommonData.analysisResult.analysisDate = datestr(now);
            fs = app.CommonData.samplingFrequency;
            fx = output.fAxis;
            tx = output.tAxis;
            %---- plot response
            smLTI = output.smoothedLTIW;
            smRand = output.smoothedRandW;
            ltidB = 10*log10(smLTI);
            semilogx(app.ResponseAxis, fx, 10*log10(smLTI), 'LineWidth',2);
            grid(app.ResponseAxis,'on');
            hold(app.ResponseAxis,'all');
            semilogx(app.ResponseAxis, fx, 10*log10(smRand), 'LineWidth',2)
            semilogx(app.ResponseAxis, fx, 10*log10(output.smoothedSigDepW), 'LineWidth',2)
            semilogx(app.ResponseAxis, fx, 10*log10(output.smoothedSilentF), 'LineWidth',2)
            axis(app.ResponseAxis,[20 fs/2 [-80 2]+ max(ltidB(fx > 70 & fx < 15000))])
            set(app.ResponseAxis, 'fontsize', 15, 'linewidth', 2);
            xlabel(app.ResponseAxis, 'frequency (Hz)');
            ylabel(app.ResponseAxis, 'gain (dB)')
            legend(app.ResponseAxis, 'LTI', 'Random', 'SigDep', 'BGnoise', 'Location','south',...
                'Orientation','horizontal');
            [~,fn,~]=fileparts(app.CommonData.mesName);
            title(app.ResponseAxis, fn + " Flat SPL:" ...
                + num2str(output.flatSpl,'%5.1f') + " dB")
            hold(app.ResponseAxis,'off');
            %----- plot impulse response
            iResp = output.averageImpulse;
            [~, maxIdx] = max(abs(iResp));
            plot(app.ImpulseAxis, (tx-maxIdx/fs)*1000, iResp, 'LineWidth',2);
            grid(app.ImpulseAxis,'on');
            set(app.ImpulseAxis, 'fontsize', 15, 'linewidth', 2);
            axis(app.ImpulseAxis, [-5 25 min(iResp) max(iResp)])
            xlabel(app.ImpulseAxis, 'time (ms)')
            ylabel(app.ImpulseAxis, 'amplitude (rel.)')
            title(app.ImpulseAxis, 'impulse response')
            %----- prepare reverberation information
            revRawInfo = decayMap(iResp, fs);
            outrefTimeInfo = revTimeEstimate(revRawInfo);
            t60 = outrefTimeInfo.t60;
            if t60 < 0.02;t60 = 0.1;end
            %----- plot telephone range power response
            smoothedPwrResp = revRawInfo.spRangePowerResp;
            dBPwrResp = 10*log10(smoothedPwrResp);
            plot(app.CrDistAxis, revRawInfo.txBuffer, dBPwrResp, 'LineWidth',2);
            hold(app.CrDistAxis, "on")
            plot(app.CrDistAxis, outrefTimeInfo.txSel, outrefTimeInfo.dBRespSel, 'LineWidth',2);
            plot(app.CrDistAxis, [0 t60], [0 -60], 'LineWidth',2);
            text(app.CrDistAxis, t60*0.25, -10, [' est-T60:' num2str(t60*1000, '%6.0f') ' ms'] )
            grid(app.CrDistAxis,'on');
            set(app.CrDistAxis, 'fontsize', 15, 'linewidth', 2);
            axis(app.CrDistAxis, [-0.02 t60 -70 0])
            xlabel(app.CrDistAxis, 'time (s)')
            ylabel(app.CrDistAxis, 'power (dB)')
            title(app.CrDistAxis, 'power response')
            hold(app.CrDistAxis, "off")
            %----- plot reverberation map
            pwrDecayMap = 10*log10(revRawInfo.pwrDecayMap);
            %pwrDecayMapMedian = 10*log10(revRawInfo.pwrDecayMapMedian);
            tDelMap = revRawInfo.tDelMap;
            fcList = revRawInfo.fBankStr.fcList;
            nChannels = length(fcList);
            imagesc(app.ReverbAxis,[1 nChannels],[1 length(tDelMap)],...
                max(-70, pwrDecayMap));
            yTic = [-0.001 -0.0005 0 0.0005 0.001 0.002 0.005 0.01 0.02 ...
                0.05 0.1 0.2 0.5 1 2 5 10];
            yticLbl = num2str(yTic'*1000);
            yticAl = interp1(tDelMap,(1:length(tDelMap))', yTic,'linear','extrap');
            set(app.ReverbAxis, 'ytick',yticAl,'yticklabel',yticLbl);
            set(app.ReverbAxis, 'fontsize', 13, 'linewidth', 2);
            axis(app.ReverbAxis,'xy');
            axis(app.ReverbAxis,[1 nChannels 1 length(tDelMap)])
            colormap(app.ReverbAxis, "turbo");
            colorbar(app.ReverbAxis,"northoutside")
            xlabel(app.ReverbAxis, 'frequency (Hz)')
            ylabel(app.ReverbAxis, 'time (ms)')
            xTick = interp1(log(fcList),1:nChannels,log([100 1000 10000]));
            xmTick10 = interp1(log(fcList),1:nChannels,log(10*(2:9)));
            xmTick100 = interp1(log(fcList),1:nChannels,log(100*(2:9)));
            xmTick1000 = interp1(log(fcList),1:nChannels,log(1000*(2:9)));
            xmTick10000 = interp1(log(fcList),1:nChannels,log(10000*(2:9)),'linear','extrap');
            xTickLabel = {'10^2', '10^3', '10^4'};
            for ii = 1:length(yticAl)
                yline(app.ReverbAxis,yticAl(ii),'w:');
            end
            yline(app.ReverbAxis,yticAl([3 8 11 14 17]),'w');
            xline(app.ReverbAxis,xTick,'w')
            xline(app.ReverbAxis,xmTick10,'w:')
            xline(app.ReverbAxis,xmTick100,'w:')
            xline(app.ReverbAxis,xmTick1000,'w:')
            xline(app.ReverbAxis,xmTick10000,'w:')
            title(app.ReverbAxis,[])
            set(app.ReverbAxis,'xtick', xTick, 'xticklabel', xTickLabel);
            app.ANALYSYSButton.Enable = 'off';
            app.REPORTButton.Enable = 'on';
            app.UIFigure.Pointer='arrow';
            drawnow;
            app.RightPanel.Visible = 'on';
            app.RightPanel.Enable = 'on';
        end

        % Button pushed function: InspectpreviouslyrecordedfilesButton
        function InspectpreviouslyrecordedfilesButtonPushed(app, event)
            app.CommonData.logStging = {"Inspect previously recorded files pushed:" + datestr(now)};
            writeLog(app);
            [testName, testPath] = uigetfile("*.wav", "Select test signal file:");
            [mesName, mesPath] = uigetfile("*.wav", "Select measured file:");
            app.CommonData.testName = string(testPath) + string(testName);
            app.CommonData.mesName = string(mesPath) + string(mesName);
            app.ANALYSYSButton.Enable = 'on';
            app.RightPanel.Enable = 'off';
            app.REPORTButton.Enable = 'off';
        end

        % Button pushed function: CalibrationStartButton
        function CalibrationStartButtonPushed(app, event)
            app.CommonData.logStging = {"Calibration pushed:" + datestr(now)};
            writeLog(app);
            fs = app.CommonData.samplingFrequency;
            y = app.CommonData.testCalTone;
            y = [y y];
            app.Memo5sButton.Enable = 'off';
            app.CommonData.audioRecorder = audiorecorder(fs,24,1,app.CommonData.inputDeviceID);
            record(app.CommonData.audioRecorder);
            app.CommonData.audioPlayer = audioplayer(y,fs,24,app.CommonData.outputDeviceID);
            play(app.CommonData.audioPlayer);
            pause(0.1);
            count = 19.9;
            app.AweightSPLdBDropDown.Value = '0';
            app.Label.Visible = 'on';
            app.CalibratedLamp.Visible = 'off';
            app.CalibrationStartButton.Enable = 'off';
            app.AweightSPLdBDropDown.Enable = 'on';
            app.TestPanel.Enable = 'off';
            app.TestsignalspecificationPanel.Enable = 'off';
            app.AnalysisandreportpanelPanel.Enable = 'off';
            while count > 0 && isplaying(app.CommonData.audioPlayer)
                pause(0.1)
                count = count - 0.1;
                switch app.CalibratedLamp.Visible
                    case 'off'
                        app.Label.Text = num2str(count,'%04.1f');
                end
            end
                switch app.CalibratedLamp.Visible
                    case 'on'
                        app.Label.Text = ...
                            [num2str(app.CommonData.calLevel,'%5.1f') ' dB'];
                    case 'off'
                        app.Label.Visible = 'off';
                end
            %app.Label.Visible = 'off';
            app.AweightSPLdBDropDown.Enable = 'off';
            app.CalibrationStartButton.Enable = 'on';
            app.TestPanel.Enable = 'on';
            app.TestsignalspecificationPanel.Enable = 'on';
            app.SavemeasureddataButton.Enable = 'off';
            app.ANALYSYSButton.Enable = 'off';
            app.REPORTButton.Enable = 'off';
            app.AnalysisandreportpanelPanel.Enable = 'on';
            app.CommonData.logStging = {"calibrated:" + datestr(now), ...
                "A-weight sound pressure level:" + app.AweightSPLdBDropDown.Value + " dB", ...
                "calibration coefficient:" + app.Label.Text + " dB"};
            writeLog(app);
            app.Memo5sButton.Enable = 'on';
        end

        % Value changed function: AweightSPLdBDropDown
        function AweightSPLdBDropDownValueChanged(app, event)
            fs = app.CommonData.samplingFrequency;
            value = str2double(app.AweightSPLdBDropDown.Value);
            if value > 0
                x = getaudiodata(app.CommonData.audioRecorder);
                tmpStddB = 20*log10(std(x(end-fs:end)));
                app.CommonData.calLevel = value - tmpStddB;
                app.CalibratedLamp.Visible = 'on';
                stop(app.CommonData.audioPlayer);
                app.Label.Text = [num2str(app.CommonData.calLevel,'%5.1f') ' dB'];
            end
        end

        % Button pushed function: LoadtestsignalsettingButton
        function LoadtestsignalsettingButtonPushed(app, event)
            %defaultFile = 'setting.txt';
            [defaultFile, workDirectory] = uigetfile('*.txt');
            app.CommonData.settingFileName = string(workDirectory) + string(defaultFile);
            app.CommonData.workDirectory = workDirectory;
            if app.CommonData.logFileFid ~= -1
                app.CommonData.logStging = {"setting changed:" + datestr(now)};
                writeLog(app);
                fclose(app.CommonData.logFileFid);
                app.CommonData.veryFirstTime = false;
            else
                app.CommonData.veryFirstTime = true;
            end
            app = settingFileRead(app);
            for ii = 1:app.CommonData.loopTypeCount
                [x1, fs] = audioread(workDirectory + app.CommonData.loopFiles(ii));
                app.CommonData.samplingFrequency = fs;
                app.CommonData.loopLength = length(x1);
                app.CommonData.loop(ii).x = x1;
                app.LoopDropDown.Items(ii) = ...
                    {char(erase(app.CommonData.loopFiles(ii), ".wav"))};
            end
            app.LoopDropDown.Value = ...
                {char(erase(app.CommonData.loopFiles(1), ".wav"))};
            app.CommonData.loopCount = 4;
            logFileName = workDirectory + "log" + datestr(now,30) + ".txt";
            app.CommonData.logFileFid = fopen(logFileName,"A");
            if app.CommonData.veryFirstTime
                app.CommonData.logStging = {"Measurement started:" + datestr(now)};
                writeLog(app);
            end
            app.CommonData.logStging = {"setting read:" + datestr(now), ...
                "setting file:" + defaultFile, "directory:" + workDirectory, ...
                "sampling frequency:" + num2str(fs) + " Hz", ...
                "input device:" + app.CommonData.inputDeviceName, ...
                "output device:" + app.CommonData.outputDeviceName, ...
                "loop length:" + num2str(app.CommonData.loopLength) + " samples"};
            writeLog(app);
            generateCalibrationTone(app);
        end

        % Button pushed function: REPORTButton
        function REPORTButtonPushed(app, event)
            app.CommonData.logStging = {"REPORT pushed:" + datestr(now)};
            writeLog(app);
            [fp,fn,~]=fileparts(app.CommonData.analysisResult.TesfFile);
            outFnameFig = fp + "/" + "fig" + fn + ".jpg";
            exportgraphics(app.RightPanel,outFnameFig);
            outFnameResult = fp + "/" + "anl" + fn + ".mat";
            analysisResult = app.CommonData.analysisResult;
            save(outFnameResult,'analysisResult');
            app.REPORTButton.Enable = 'off';
        end

        % Button pushed function: Memo5sButton
        function Memo5sButtonPushed(app, event)
            app.CommonData.logStging = {"MEMO pushed:" + datestr(now)};
            writeLog(app);
            fs = app.CommonData.samplingFrequency;
            app.CommonData.audioRecorder = audiorecorder(fs,24,1,app.CommonData.inputDeviceID);
            record(app.CommonData.audioRecorder);
            app.Label_2.Visible = 'on';
            timeLeft = 5;
            while timeLeft > 0
                app.Label_2.Text = num2str(timeLeft,'%4.1f');
                pause(0.1);
                timeLeft = timeLeft - 0.1;
            end
            app.Label_2.Visible = 'off';
            y = getaudiodata(app.CommonData.audioRecorder);
            memoFileName = app.CommonData.workDirectory + "memo" + datestr(now,30) + ".wav";
            audiowrite(memoFileName, y, fs,"BitsPerSample",24);
            stop(app.CommonData.audioRecorder);
        end

        % Value changed function: TestmodeDropDown
        function TestmodeDropDownValueChanged(app, event)
            value = app.TestmodeDropDown.Value;
            app.CommonData.logStging = {"Testmode changed:" + datestr(now), ...
                "Test mode:" + value};
            writeLog(app);
        end

        % Value changed function: SGmodeDropDown
        function SGmodeDropDownValueChanged(app, event)
            value = app.SGmodeDropDown.Value;
            app.CommonData.logStging = {"SGmode changed:" + datestr(now), ...
                "SGmode:" + value};
            writeLog(app);
        end

        % Value changed function: LoopDropDown
        function LoopDropDownValueChanged(app, event)
            value = app.LoopDropDown.Value;
            app.CommonData.logStging = {"contents changed:" + datestr(now), ...
                "name:" + value};
            writeLog(app);
        end

        % Drop down opening function: CountDropDown
        function CountDropDownOpening(app, event)
            value = app.CountDropDown.Value;
            app.CommonData.logStging = {"loop count changed:" + datestr(now), ...
                "loop count:" + value};
            writeLog(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {831, 831};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DFTA5SPLabel = uilabel(app.LeftPanel);
            app.DFTA5SPLabel.Text = 'DFTA5SP';
            app.Memo5sButton = uibutton(app.LeftPanel, 'push');
            app.Memo5sButton.ButtonPushedFcn = createCallbackFcn(app, @Memo5sButtonPushed, true);
            app.Memo5sButton.Text = 'Memo 5s';
            app.CalibrationPanel = uipanel(app.LeftPanel);
            app.CalibrationPanel.Title = 'Calibration';
            app.CalibratedLampLabel = uilabel(app.CalibrationPanel);
            app.CalibratedLampLabel.Text = 'Calibrated';
            app.CalibratedLamp = uilamp(app.CalibrationPanel);
            app.AweightSPLdBDropDownLabel = uilabel(app.CalibrationPanel);
            app.AweightSPLdBDropDownLabel.Text = 'A-weight SPL (dB)';
            app.AweightSPLdBDropDown = uidropdown(app.CalibrationPanel);
            app.AweightSPLdBDropDown.Items = {'70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '83', '84', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94', '95', '96', '97', '98', '99', '100', '0', ''};
            app.AweightSPLdBDropDown.ValueChangedFcn = createCallbackFcn(app, @AweightSPLdBDropDownValueChanged, true);
            app.AweightSPLdBDropDown.Value = '0';
            app.CalibrationStartButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrationStartButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationStartButtonPushed, true);
            app.CalibrationStartButton.Text = 'Calibration Start';
            app.Label = uilabel(app.CalibrationPanel);
            app.Label.Text = '20.0';
            app.TestPanel = uipanel(app.LeftPanel);
            app.TestPanel.Title = 'Test';
            app.MONOLButton = uibutton(app.TestPanel, 'push');
            app.MONOLButton.ButtonPushedFcn = createCallbackFcn(app, @MONOLButtonPushed, true);
            app.MONOLButton.Text = 'MONO-L';
            app.MONORButton = uibutton(app.TestPanel, 'push');
            app.MONORButton.ButtonPushedFcn = createCallbackFcn(app, @MONORButtonPushed, true);
            app.MONORButton.Text = 'MONO-R';
            app.MONOLRButton = uibutton(app.TestPanel, 'push');
            app.MONOLRButton.ButtonPushedFcn = createCallbackFcn(app, @MONOLRButtonPushed, true);
            app.MONOLRButton.Text = 'MONO-LR';
            app.STEREOLRButton = uibutton(app.TestPanel, 'push');
            app.STEREOLRButton.Enable = 'off';
            app.STEREOLRButton.Text = 'STEREO-LR';
            app.STEREOLButton = uibutton(app.TestPanel, 'push');
            app.STEREOLButton.Enable = 'off';
            app.STEREOLButton.Text = 'STEREO-L';
            app.STEREORButton = uibutton(app.TestPanel, 'push');
            app.STEREORButton.Enable = 'off';
            app.STEREORButton.Text = 'STEREO-R';
            app.SavemeasureddataButton = uibutton(app.TestPanel, 'push');
            app.SavemeasureddataButton.ButtonPushedFcn = createCallbackFcn(app, @SavemeasureddataButtonPushed, true);
            app.SavemeasureddataButton.Text = 'Save measured data';
            app.AnalysisandreportpanelPanel = uipanel(app.LeftPanel);
            app.AnalysisandreportpanelPanel.Title = 'Analysis and report panel';
            app.ANALYSYSButton = uibutton(app.AnalysisandreportpanelPanel, 'push');
            app.ANALYSYSButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYSYSButtonPushed, true);
            app.ANALYSYSButton.Text = 'ANALYSYS';
            app.REPORTButton = uibutton(app.AnalysisandreportpanelPanel, 'push');
            app.REPORTButton.ButtonPushedFcn = createCallbackFcn(app, @REPORTButtonPushed, true);
            app.REPORTButton.Text = 'REPORT';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.InspectpreviouslyrecordedfilesButton = uibutton(app.LeftPanel, 'push');
            app.InspectpreviouslyrecordedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @InspectpreviouslyrecordedfilesButtonPushed, true);
            app.InspectpreviouslyrecordedfilesButton.Text = 'Inspect previously recorded files';
            app.LoadtestsignalsettingButton = uibutton(app.LeftPanel, 'push');
            app.LoadtestsignalsettingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtestsignalsettingButtonPushed, true);
            app.LoadtestsignalsettingButton.Text = 'Load test signal setting';
            app.TestsignalspecificationPanel = uipanel(app.LeftPanel);
            app.TestsignalspecificationPanel.Title = 'Test signal specification';
            app.TypeLabel = uilabel(app.TestsignalspecificationPanel);
            app.TypeLabel.Text = 'Type';
            app.CreditLabel = uilabel(app.TestsignalspecificationPanel);
            app.CreditLabel.Text = 'Credit';
            app.TestmodeDropDownLabel = uilabel(app.TestsignalspecificationPanel);
            app.TestmodeDropDownLabel.Text = 'Test mode';
            app.TestmodeDropDown = uidropdown(app.TestsignalspecificationPanel);
            app.TestmodeDropDown.Items = {'Basic', 'Detail', ''};
            app.TestmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TestmodeDropDownValueChanged, true);
            app.TestmodeDropDown.Value = 'Basic';
            app.LoopDropDownLabel = uilabel(app.TestsignalspecificationPanel);
            app.LoopDropDownLabel.Text = 'Loop';
            app.LoopDropDown = uidropdown(app.TestsignalspecificationPanel);
            app.LoopDropDown.Items = {'holiday', 'laboratory', 'legend', 'space', '', ''};
            app.LoopDropDown.ValueChangedFcn = createCallbackFcn(app, @LoopDropDownValueChanged, true);
            app.LoopDropDown.Value = 'holiday';
            app.CountDropDownLabel = uilabel(app.TestsignalspecificationPanel);
            app.CountDropDownLabel.Text = 'Count';
            app.CountDropDown = uidropdown(app.TestsignalspecificationPanel);
            app.CountDropDown.Items = {'2', '4', '8', '16', '', ''};
            app.CountDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CountDropDownOpening, true);
            app.CountDropDown.ValueChangedFcn = createCallbackFcn(app, @CountDropDownValueChanged, true);
            app.CountDropDown.Value = '4';
            app.SGmodeDropDownLabel = uilabel(app.TestsignalspecificationPanel);
            app.SGmodeDropDownLabel.Text = 'S.G.mode';
            app.SGmodeDropDown = uidropdown(app.TestsignalspecificationPanel);
            app.SGmodeDropDown.Items = {'av-10dB', 'av-0dB', 'sDep-10dB'};
            app.SGmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @SGmodeDropDownValueChanged, true);
            app.SGmodeDropDown.Value = 'sDep-10dB';
            app.byLabel = uilabel(app.TestsignalspecificationPanel);
            app.byLabel.Text = 'by';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '5.0';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResponseAxis = uiaxes(app.RightPanel);
            title(app.ResponseAxis, 'Title')
            xlabel(app.ResponseAxis, 'X')
            ylabel(app.ResponseAxis, 'Y')
            zlabel(app.ResponseAxis, 'Z')
            app.ReverbAxis = uiaxes(app.RightPanel);
            title(app.ReverbAxis, 'Title')
            xlabel(app.ReverbAxis, 'X')
            ylabel(app.ReverbAxis, 'Y')
            zlabel(app.ReverbAxis, 'Z')
            app.ImpulseAxis = uiaxes(app.RightPanel);
            title(app.ImpulseAxis, 'Title')
            xlabel(app.ImpulseAxis, 'X')
            ylabel(app.ImpulseAxis, 'Y')
            zlabel(app.ImpulseAxis, 'Z')
            app.CrDistAxis = uiaxes(app.RightPanel);
            title(app.CrDistAxis, 'Title')
            xlabel(app.CrDistAxis, 'X')
            ylabel(app.CrDistAxis, 'Y')
            zlabel(app.CrDistAxis, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dfta5sp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end